classdef BAM_Online_Analysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        startmsEditField_2Label       matlab.ui.control.Label
        BarEnd                        matlab.ui.control.EditField
        startmsEditFieldLabel         matlab.ui.control.Label
        BarStart                      matlab.ui.control.EditField
        FunctionButtonGroup           matlab.ui.container.ButtonGroup
        UserDefinedButton             matlab.ui.control.ToggleButton
        PSTHButton                    matlab.ui.control.ToggleButton
        BarButton                     matlab.ui.control.ToggleButton
        EnableButton_8                matlab.ui.control.StateButton
        EnableButton_7                matlab.ui.control.StateButton
        EnableButton_6                matlab.ui.control.StateButton
        EnableButton_5                matlab.ui.control.StateButton
        EnableButton_4                matlab.ui.control.StateButton
        EnableButton_3                matlab.ui.control.StateButton
        EnableButton_2                matlab.ui.control.StateButton
        EnableButton_1                matlab.ui.control.StateButton
        EyePanel                      matlab.ui.container.Panel
        WinSizeSpinnerLabel           matlab.ui.control.Label
        WinSizeSpinner                matlab.ui.control.Spinner
        TimeSpinner                   matlab.ui.control.Spinner
        CYSpinner                     matlab.ui.control.Spinner
        CXSpinner_2Label_2            matlab.ui.control.Label
        CXSpinner                     matlab.ui.control.Spinner
        CXSpinner_2Label              matlab.ui.control.Label
        OnsetLabel                    matlab.ui.control.Label
        RecenterButton                matlab.ui.control.Button
        ValidTrialLabel               matlab.ui.control.Label
        EyeSummary                    matlab.ui.control.UIAxes
        BAMOnlineAnalysisSystemPanel  matlab.ui.container.Panel
        SelectSession                 matlab.ui.control.DropDown
        SessionLabel                  matlab.ui.control.Label
        RunButton                     matlab.ui.control.Button
        MemoryUseage                  matlab.ui.control.SemicircularGauge
        SessionTimeLabel              matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
        CategoryGrpstat               matlab.ui.control.UIAxes
        UIAxes_8                      matlab.ui.control.UIAxes
        UIAxes_7                      matlab.ui.control.UIAxes
        UIAxes_6                      matlab.ui.control.UIAxes
        UIAxes_5                      matlab.ui.control.UIAxes
        UIAxes_4                      matlab.ui.control.UIAxes
        UIAxes_3                      matlab.ui.control.UIAxes
        UIAxes_2                      matlab.ui.control.UIAxes
        UIAxes_1                      matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function updateimage(app,imagefile)
            
            % For corn.tif, read the second image in the file
            if strcmp(imagefile,'corn.tif')
                im = imread('corn.tif', 2);
            else
                try
                    im = imread(imagefile);
                catch ME
                    % If problem reading image, display error message
                    uialert(app.UIFigure, ME.message, 'Image Error');
                    return;
                end            
            end 
            
            % Create histograms based on number of color channels
            switch size(im,3)
                case 1
                    % Display the grayscale image
                    imagesc(app.ImageAxes,im);
                    
                    % Plot all histograms with the same data for grayscale
                    histr = histogram(app.RedAxes, im, 'FaceColor',[1 0 0],'EdgeColor', 'none');
                    histg = histogram(app.GreenAxes, im, 'FaceColor',[0 1 0],'EdgeColor', 'none');
                    histb = histogram(app.BlueAxes, im, 'FaceColor',[0 0 1],'EdgeColor', 'none');
                    
                case 3
                    % Display the truecolor image
                    imagesc(app.ImageAxes,im);
                    
                    % Plot the histograms
                    histr = histogram(app.RedAxes, im(:,:,1), 'FaceColor', [1 0 0], 'EdgeColor', 'none');
                    histg = histogram(app.GreenAxes, im(:,:,2), 'FaceColor', [0 1 0], 'EdgeColor', 'none');
                    histb = histogram(app.BlueAxes, im(:,:,3), 'FaceColor', [0 0 1], 'EdgeColor', 'none');
                    
                otherwise
                    % Error when image is not grayscale or truecolor
                    uialert(app.UIFigure, 'Image must be grayscale or truecolor.', 'Image Error');
                    return;
            end
                % Get largest bin count
                maxr = max(histr.BinCounts);
                maxg = max(histg.BinCounts);
                maxb = max(histb.BinCounts);
                maxcount = max([maxr maxg maxb]);
                
                % Set y axes limits based on largest bin count
                app.RedAxes.YLim = [0 maxcount];
                app.RedAxes.YTick = round([0 maxcount/2 maxcount], 2, 'significant');
                app.GreenAxes.YLim = [0 maxcount];
                app.GreenAxes.YTick = round([0 maxcount/2 maxcount], 2, 'significant');
                app.BlueAxes.YLim = [0 maxcount];
                app.BlueAxes.YTick = round([0 maxcount/2 maxcount], 2, 'significant');
         
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Configure image axes
            global BAM_config BAM_analysis
            BAM_config = load('default_params.mat').BAM_config;
            BAM_config.root_dir = fN_localize_app();
            BAM_analysis = struct;
            imagesc(app.EyeSummary, rand(100))
            bar_plot = bar(app.CategoryGrpstat,rand([1,4]));
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            global BAM_config BAM_analysis 

            % copy fake data
            debug_mode = 1;
            BAM_config.today_name = strrep(datestr(datetime), ' ', '_');
            BAM_config.today_name = BAM_config.today_name(1:11);
            if(debug_mode)
                BAM_config.today_name = '24-Feb-2023';
                rmdir 'C:\Users\DELL\Documents\GitHub\BAM_online_analysis\data\*' s
                copyfile('C:\Users\DELL\Desktop\data', 'C:\Users\DELL\Documents\GitHub\BAM_online_analysis\data')
            end

            t0=GetSecs;
            while(1)
                        while(GetSecs-t0>BAM_config.combine_interval)
                            [BAM_config, BAM_analysis, app]=fN_combine_mat(BAM_config, BAM_analysis, app);
                            t0=GetSecs;
                            [BAM_config, BAM_analysis, app]=fN_plot_stats(BAM_config, BAM_analysis, app);
                        end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Image Histograms';
            app.UIAxes_1 = uiaxes(app.UIFigure);
            title(app.UIAxes_1, 'Title')
            xlabel(app.UIAxes_1, 'X')
            ylabel(app.UIAxes_1, 'Y')
            zlabel(app.UIAxes_1, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Title')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_4 = uiaxes(app.UIFigure);
            title(app.UIAxes_4, 'Title')
            xlabel(app.UIAxes_4, 'X')
            ylabel(app.UIAxes_4, 'Y')
            zlabel(app.UIAxes_4, 'Z')
            app.UIAxes_5 = uiaxes(app.UIFigure);
            title(app.UIAxes_5, 'Title')
            xlabel(app.UIAxes_5, 'X')
            ylabel(app.UIAxes_5, 'Y')
            zlabel(app.UIAxes_5, 'Z')
            app.UIAxes_6 = uiaxes(app.UIFigure);
            title(app.UIAxes_6, 'Title')
            xlabel(app.UIAxes_6, 'X')
            ylabel(app.UIAxes_6, 'Y')
            zlabel(app.UIAxes_6, 'Z')
            app.UIAxes_7 = uiaxes(app.UIFigure);
            title(app.UIAxes_7, 'Title')
            xlabel(app.UIAxes_7, 'X')
            ylabel(app.UIAxes_7, 'Y')
            zlabel(app.UIAxes_7, 'Z')
            app.UIAxes_8 = uiaxes(app.UIFigure);
            title(app.UIAxes_8, 'Title')
            xlabel(app.UIAxes_8, 'X')
            ylabel(app.UIAxes_8, 'Y')
            zlabel(app.UIAxes_8, 'Z')
            app.CategoryGrpstat = uiaxes(app.UIFigure);
            xlabel(app.CategoryGrpstat, 'Category')
            ylabel(app.CategoryGrpstat, 'Freq')
            app.CategoryGrpstat.XAxisLocation = 'origin';
            app.CategoryGrpstat.XTick = [];
            app.CategoryGrpstat.YAxisLocation = 'origin';
            app.CategoryGrpstat.YTick = [];
            app.CategoryGrpstat.ZTick = [];
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.BAMOnlineAnalysisSystemPanel = uipanel(app.UIFigure);
            app.BAMOnlineAnalysisSystemPanel.Title = 'BAM Online Analysis System';
            app.SessionTimeLabel = uilabel(app.BAMOnlineAnalysisSystemPanel);
            app.SessionTimeLabel.Text = 'Session Time';
            app.MemoryUseage = uigauge(app.BAMOnlineAnalysisSystemPanel, 'semicircular');
            app.MemoryUseage.ScaleDirection = 'counterclockwise';
            app.RunButton = uibutton(app.BAMOnlineAnalysisSystemPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Icon = fullfile(pathToMLAPP, 'Blab_logo.png');
            app.RunButton.Text = '';
            app.SessionLabel = uilabel(app.BAMOnlineAnalysisSystemPanel);
            app.SessionLabel.Text = 'Session';
            app.SelectSession = uidropdown(app.BAMOnlineAnalysisSystemPanel);
            app.EyePanel = uipanel(app.UIFigure);
            app.EyePanel.Title = 'Eye';
            app.EyeSummary = uiaxes(app.EyePanel);
            zlabel(app.EyeSummary, 'Z')
            app.EyeSummary.XTick = [];
            app.EyeSummary.YTick = [];
            app.EyeSummary.YTickLabel = '';
            colormap(app.EyeSummary, 'turbo')
            app.ValidTrialLabel = uilabel(app.EyePanel);
            app.ValidTrialLabel.Text = 'Valid Trial';
            app.RecenterButton = uibutton(app.EyePanel, 'push');
            app.RecenterButton.Text = 'Recenter';
            app.OnsetLabel = uilabel(app.EyePanel);
            app.OnsetLabel.Text = 'Time%';
            app.CXSpinner_2Label = uilabel(app.EyePanel);
            app.CXSpinner_2Label.Text = 'CX';
            app.CXSpinner = uispinner(app.EyePanel);
            app.CXSpinner.Step = 0.1;
            app.CXSpinner_2Label_2 = uilabel(app.EyePanel);
            app.CXSpinner_2Label_2.Text = 'CY';
            app.CYSpinner = uispinner(app.EyePanel);
            app.CYSpinner.Step = 0.1;
            app.TimeSpinner = uispinner(app.EyePanel);
            app.TimeSpinner.Step = 5;
            app.TimeSpinner.Limits = [5 100];
            app.TimeSpinner.Value = 80;
            app.WinSizeSpinner = uispinner(app.EyePanel);
            app.WinSizeSpinner.Value = 2;
            app.WinSizeSpinnerLabel = uilabel(app.EyePanel);
            app.WinSizeSpinnerLabel.Text = 'WinSize';
            app.EnableButton_1 = uibutton(app.UIFigure, 'state');
            app.EnableButton_1.Text = 'Enable';
            app.EnableButton_2 = uibutton(app.UIFigure, 'state');
            app.EnableButton_2.Text = 'Enable';
            app.EnableButton_3 = uibutton(app.UIFigure, 'state');
            app.EnableButton_3.Text = 'Enable';
            app.EnableButton_4 = uibutton(app.UIFigure, 'state');
            app.EnableButton_4.Text = 'Enable';
            app.EnableButton_5 = uibutton(app.UIFigure, 'state');
            app.EnableButton_5.Text = 'Enable';
            app.EnableButton_6 = uibutton(app.UIFigure, 'state');
            app.EnableButton_6.Text = 'Enable';
            app.EnableButton_7 = uibutton(app.UIFigure, 'state');
            app.EnableButton_7.Text = 'Enable';
            app.EnableButton_8 = uibutton(app.UIFigure, 'state');
            app.EnableButton_8.Text = 'Enable';
            app.FunctionButtonGroup = uibuttongroup(app.UIFigure);
            app.FunctionButtonGroup.Title = 'Function';
            app.BarButton = uitogglebutton(app.FunctionButtonGroup);
            app.BarButton.Text = 'Bar';
            app.BarButton.Value = true;
            app.PSTHButton = uitogglebutton(app.FunctionButtonGroup);
            app.PSTHButton.Text = 'PSTH';
            app.UserDefinedButton = uitogglebutton(app.FunctionButtonGroup);
            app.UserDefinedButton.Text = 'User Defined';
            app.BarStart = uieditfield(app.UIFigure, 'text');
            app.BarStart.Value = '70';
            app.startmsEditFieldLabel = uilabel(app.UIFigure);
            app.startmsEditFieldLabel.Text = 'start(ms)';
            app.BarEnd = uieditfield(app.UIFigure, 'text');
            app.BarEnd.Value = '200';
            app.startmsEditField_2Label = uilabel(app.UIFigure);
            app.startmsEditField_2Label.Text = 'start(ms)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BAM_Online_Analysis

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end