classdef BAM_Online_Loading < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        OnlineSavingUIFigure         matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        Image                        matlab.ui.control.Image
        SessionSpinner               matlab.ui.control.Spinner
        SessionSpinnerLabel          matlab.ui.control.Label
        ChannelUsingLFP2             matlab.ui.control.CheckBox
        ChannelUsingLFP1             matlab.ui.control.CheckBox
        Spinners24                   matlab.ui.control.Spinner
        SEG21Label_4                 matlab.ui.control.Label
        Spinners23                   matlab.ui.control.Spinner
        SEG21Label_3                 matlab.ui.control.Label
        Spinners22                   matlab.ui.control.Spinner
        SEG21Label_2                 matlab.ui.control.Label
        Spinners21                   matlab.ui.control.Spinner
        SEG21Label                   matlab.ui.control.Label
        Spinners14                   matlab.ui.control.Spinner
        SEG14Label                   matlab.ui.control.Label
        Spinners13                   matlab.ui.control.Spinner
        SEG13Label                   matlab.ui.control.Label
        Spinners12                   matlab.ui.control.Spinner
        SEG12Label                   matlab.ui.control.Label
        Spinners11                   matlab.ui.control.Spinner
        SEG11SpinnerLabel            matlab.ui.control.Label
        ElectrodeAdvancedButton      matlab.ui.control.Button
        SelectSet                    matlab.ui.control.DropDown
        DatasetDropDownLabel         matlab.ui.control.Label
        RightPanel                   matlab.ui.container.Panel
        BAMOnlineLoadingSystemLabel  matlab.ui.control.Label
        SavingLamp                   matlab.ui.control.Lamp
        SavingLampLabel              matlab.ui.control.Label
        StopOnlineSavingButton       matlab.ui.control.Button
        StartOnlineSavingButton      matlab.ui.control.Button
        delay_measurement            matlab.ui.control.Label
        DataCapture                  matlab.ui.control.Table
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global BAM_config GAM_data
            root_dir = fN_localize_app;
            addpath(genpath(pwd))
            [BAM_config, GAM_data, app] = fN_initialize(app);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.OnlineSavingUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {486, 486};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {188, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: StartOnlineSavingButton
        function StartOnlineSavingButtonPushed(app, event)
            global BAM_config BAM_data
            [BAM_config,BAM_data, app] = fN_refresh_electrode(BAM_config, BAM_data, app);
            BAM_config.is_saving = 1;
            app = fN_change_gui_color(app,BAM_config,BAM_data);
            [BAM_config,BAM_data, app] = fN_saving(BAM_config, BAM_data, app);
        end

        % Button pushed function: StopOnlineSavingButton
        function StopOnlineSavingButtonPushed(app, event)
            global BAM_config BAM_data
            BAM_config.is_saving = 0;
            app = fN_change_gui_color(app,BAM_config,BAM_data);
        end

        % Button pushed function: ElectrodeAdvancedButton
        function ElectrodeAdvancedButtonPushed(app, event)
             global BAM_config BAM_data
             StopOnlineSavingButtonPushed(app, event)
             [BAM_config,BAM_data, app] = fN_advance(BAM_config, BAM_data, app);
             
        end

        % Value changed function: ChannelUsingLFP1, ChannelUsingLFP2, 
        % Spinners11, Spinners12, Spinners13, Spinners14, Spinners21, 
        % Spinners22, Spinners23, Spinners24
        function ElectrodeChanged(app, event)
             global BAM_config BAM_data
             StopOnlineSavingButtonPushed(app, event)
             [BAM_config,BAM_data, app] = fN_refresh_electrode(BAM_config, BAM_data, app);
             StartOnlineSavingButtonPushed(app, event)
        end

        % Value changed function: SessionSpinner
        function NewSession(app, event)
            global BAM_config BAM_data
            value = app.SessionSpinner.Value;
            [BAM_config, BAM_data, app] = fN_initialize(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.OnlineSavingUIFigure = uifigure('Visible', 'off');
            app.OnlineSavingUIFigure.Name = 'Online Saving';
            app.OnlineSavingUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.OnlineSavingUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.DatasetDropDownLabel = uilabel(app.LeftPanel);
            app.DatasetDropDownLabel.Text = 'Dataset';
            app.SelectSet = uidropdown(app.LeftPanel);
            app.SelectSet.Items = {'Option 3', 'Option 4'};
            app.SelectSet.Value = 'Option 3';
            app.ElectrodeAdvancedButton = uibutton(app.LeftPanel, 'push');
            app.ElectrodeAdvancedButton.ButtonPushedFcn = createCallbackFcn(app, @ElectrodeAdvancedButtonPushed, true);
            app.ElectrodeAdvancedButton.Text = 'Electrode Advanced';
            app.SEG11SpinnerLabel = uilabel(app.LeftPanel);
            app.SEG11SpinnerLabel.Text = 'SEG1-1';
            app.Spinners11 = uispinner(app.LeftPanel);
            app.Spinners11.ValueChangedFcn = createCallbackFcn(app, @ElectrodeChanged, true);
            app.SEG12Label = uilabel(app.LeftPanel);
            app.SEG12Label.Text = 'SEG1-2';
            app.Spinners12 = uispinner(app.LeftPanel);
            app.Spinners12.ValueChangedFcn = createCallbackFcn(app, @ElectrodeChanged, true);
            app.SEG13Label = uilabel(app.LeftPanel);
            app.SEG13Label.Text = 'SEG1-3';
            app.Spinners13 = uispinner(app.LeftPanel);
            app.Spinners13.ValueChangedFcn = createCallbackFcn(app, @ElectrodeChanged, true);
            app.SEG14Label = uilabel(app.LeftPanel);
            app.SEG14Label.Text = 'SEG1-4';
            app.Spinners14 = uispinner(app.LeftPanel);
            app.Spinners14.ValueChangedFcn = createCallbackFcn(app, @ElectrodeChanged, true);
            app.SEG21Label = uilabel(app.LeftPanel);
            app.SEG21Label.Text = 'SEG2-1';
            app.Spinners21 = uispinner(app.LeftPanel);
            app.Spinners21.ValueChangedFcn = createCallbackFcn(app, @ElectrodeChanged, true);
            app.SEG21Label_2 = uilabel(app.LeftPanel);
            app.SEG21Label_2.Text = 'SEG2-2';
            app.Spinners22 = uispinner(app.LeftPanel);
            app.Spinners22.ValueChangedFcn = createCallbackFcn(app, @ElectrodeChanged, true);
            app.SEG21Label_3 = uilabel(app.LeftPanel);
            app.SEG21Label_3.Text = 'SEG2-3';
            app.Spinners23 = uispinner(app.LeftPanel);
            app.Spinners23.ValueChangedFcn = createCallbackFcn(app, @ElectrodeChanged, true);
            app.SEG21Label_4 = uilabel(app.LeftPanel);
            app.SEG21Label_4.Text = 'SEG2-4';
            app.Spinners24 = uispinner(app.LeftPanel);
            app.Spinners24.ValueChangedFcn = createCallbackFcn(app, @ElectrodeChanged, true);
            app.ChannelUsingLFP1 = uicheckbox(app.LeftPanel);
            app.ChannelUsingLFP1.ValueChangedFcn = createCallbackFcn(app, @ElectrodeChanged, true);
            app.ChannelUsingLFP1.Text = 'LFP1';
            app.ChannelUsingLFP1.Value = true;
            app.ChannelUsingLFP2 = uicheckbox(app.LeftPanel);
            app.ChannelUsingLFP2.ValueChangedFcn = createCallbackFcn(app, @ElectrodeChanged, true);
            app.ChannelUsingLFP2.Text = 'LFP2';
            app.ChannelUsingLFP2.Value = true;
            app.SessionSpinnerLabel = uilabel(app.LeftPanel);
            app.SessionSpinnerLabel.Text = 'Session';
            app.SessionSpinner = uispinner(app.LeftPanel);
            app.SessionSpinner.ValueChangedFcn = createCallbackFcn(app, @NewSession, true);
            app.Image = uiimage(app.LeftPanel);
            app.Image.ImageSource = 'Blab_logo.png';
            app.RightPanel = uipanel(app.GridLayout);
            app.DataCapture = uitable(app.RightPanel);
            app.DataCapture.ColumnName = {'Channel'; 'Datacapture'; 'Valid Data'; 'Max Memory'};
            app.DataCapture.RowName = {};
            app.delay_measurement = uilabel(app.RightPanel);
            app.delay_measurement.Text = 'Delay Info';
            app.StartOnlineSavingButton = uibutton(app.RightPanel, 'push');
            app.StartOnlineSavingButton.ButtonPushedFcn = createCallbackFcn(app, @StartOnlineSavingButtonPushed, true);
            app.StartOnlineSavingButton.Text = 'Start Online Saving';
            app.StopOnlineSavingButton = uibutton(app.RightPanel, 'push');
            app.StopOnlineSavingButton.ButtonPushedFcn = createCallbackFcn(app, @StopOnlineSavingButtonPushed, true);
            app.StopOnlineSavingButton.Text = 'Stop Online Saving';
            app.SavingLampLabel = uilabel(app.RightPanel);
            app.SavingLampLabel.Text = 'Saving';
            app.SavingLamp = uilamp(app.RightPanel);
            app.BAMOnlineLoadingSystemLabel = uilabel(app.RightPanel);
            app.BAMOnlineLoadingSystemLabel.Text = 'BAM Online Loading System';
            % Show the figure after all components are created
            app.OnlineSavingUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BAM_Online_Loading

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.OnlineSavingUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.OnlineSavingUIFigure)
        end
    end
end